classdef PLOT_PHI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        TimeSliderLabel                 matlab.ui.control.Label
        TimeSlider                      matlab.ui.control.Slider
        WritetoimageButton              matlab.ui.control.Button
        ResultRepositoryEditFieldLabel  matlab.ui.control.Label
        ResultRepositoryEditField       matlab.ui.control.EditField
        CasetodrawDropDownLabel         matlab.ui.control.Label
        CasetodrawDropDown              matlab.ui.control.DropDown
        GridscaleEditFieldLabel         matlab.ui.control.Label
        GridscaleEditField              matlab.ui.control.NumericEditField
        TimeControlEditFieldLabel       matlab.ui.control.Label
        TimeControlEditField            matlab.ui.control.NumericEditField
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
    end

    methods (Access = private)

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            value = app.TimeSlider.Value;
            CASE_DIR=app.ResultRepositoryEditField.Value;
            load(strcat(CASE_DIR,app.CasetodrawDropDown.Value));
            del=app.GridscaleEditField.Value;
            time = prop_log(:,1);
            app.TimeSlider.Limits=[1, time(length(FIRE_BACK_IX))];
            prop_map = prop_log(:,2:end);
            SIMU_REGION_IND=3:(length(prop_map(1,:))-2);
            n=find(abs(time-value)==min(abs(time-value)));
            plot(app.UIAxes,(SIMU_REGION_IND-2)*del-del/2,prop_map(n(1),SIMU_REGION_IND),...
                ones([1,101])*(FIRE_BACK_IX(n(1)))*del-del/2,[-1:0.02:1],'b--',...
                ones([1,101])*(FIRE_FRONT_IX(n(1)))*del-del/2,[-1:0.02:1],'r--')
            app.TimeControlEditField.Value=value;
            
        end

        % Value changing function: ResultRepositoryEditField
        function ResultRepositoryEditFieldValueChanging(app, event)
            changingValue = event.Value;
            CASE_DIR=app.ResultRepositoryEditField.Value;
            CASE_NAME = dir(strcat(CASE_DIR,'*.mat'));   
            app.CasetodrawDropDown.Items={CASE_NAME.name};
        end

        % Button pushed function: WritetoimageButton
        function WritetoimageButtonPushed(app, event)
            h=figure(1);
            value = app.TimeSlider.Value;
            CASE_DIR=app.ResultRepositoryEditField.Value;
            load(strcat(CASE_DIR,app.CasetodrawDropDown.Value));
            del=app.GridscaleEditField.Value;
            time = prop_log(:,1);
            app.TimeSlider.Limits=[1, time(length(FIRE_BACK_IX))];
            prop_map = prop_log(:,2:end);
            SIMU_REGION_IND=3:(length(prop_map(1,:))-2);
            n=find(abs(time-value)==min(abs(time-value)));
            plot((SIMU_REGION_IND-2)*del-del/2,prop_map(n(1),SIMU_REGION_IND),...
                ones([1,101])*(FIRE_BACK_IX(n(1)))*del-del/2,[-1:0.02:1],'b--',...
                ones([1,101])*(FIRE_FRONT_IX(n(1)))*del-del/2,[-1:0.02:1],'r--')
            title(sprintf('t=%.2f',time(n)));
            xlabel('X [m]')
            ylabel('\phi')
            set(gca,'FontName','Times')
            set(gca,'FontSize',15)
%             imwrite(app.UIFigure)
        end

        % Value changed function: TimeControlEditField
        function TimeControlEditFieldValueChanged(app, event)
            value = app.TimeControlEditField.Value;
            app.TimeSlider.Value=value;
            TimeSliderValueChanged(app, event)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            value = app.TimeSlider.Value;
            CASE_DIR=app.ResultRepositoryEditField.Value;
            load(strcat(CASE_DIR,app.CasetodrawDropDown.Value));
            del=app.GridscaleEditField.Value;
            time = prop_log(:,1);
            n=find(abs(time-value)==min(abs(time-value)));
            if(n>1)
                app.TimeSlider.Value=time(n-1);
            else
                app.TimeSlider.Value=time(n);
            end
            TimeSliderValueChanged(app, event)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            value = app.TimeSlider.Value;
            CASE_DIR=app.ResultRepositoryEditField.Value;
            load(strcat(CASE_DIR,app.CasetodrawDropDown.Value));
            del=app.GridscaleEditField.Value;
            time = prop_log(:,1);
            n=find(abs(time-value)==min(abs(time-value)));
            if(n<length(time))
                app.TimeSlider.Value=time(n+1);
            else
                app.TimeSlider.Value=time(n);
            end
            TimeSliderValueChanged(app, event)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '\phi field history')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, '\phi')
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.WritetoimageButton = uibutton(app.UIFigure, 'push');
            app.WritetoimageButton.ButtonPushedFcn = createCallbackFcn(app, @WritetoimageButtonPushed, true);
            app.WritetoimageButton.Text = 'Write to image';
            app.ResultRepositoryEditFieldLabel = uilabel(app.UIFigure);
            app.ResultRepositoryEditFieldLabel.Text = 'Result Repository';
            app.ResultRepositoryEditField = uieditfield(app.UIFigure, 'text');
            app.ResultRepositoryEditField.ValueChangingFcn = createCallbackFcn(app, @ResultRepositoryEditFieldValueChanging, true);
            app.CasetodrawDropDownLabel = uilabel(app.UIFigure);
            app.CasetodrawDropDownLabel.Text = 'Case to draw';
            app.CasetodrawDropDown = uidropdown(app.UIFigure);
            app.GridscaleEditFieldLabel = uilabel(app.UIFigure);
            app.GridscaleEditFieldLabel.Text = 'Grid scale';
            app.GridscaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeControlEditFieldLabel = uilabel(app.UIFigure);
            app.TimeControlEditFieldLabel.Text = 'Time Control';
            app.TimeControlEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeControlEditField.ValueChangedFcn = createCallbackFcn(app, @TimeControlEditFieldValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '-';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '+';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PLOT_PHI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end